classdef testapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        UIAxes        matlab.ui.control.UIAxes
        SliderLabel   matlab.ui.control.Label
        Slider        matlab.ui.control.Slider
        LoadButton    matlab.ui.control.Button
        ExportButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        Year
        Data
        SmoothedData
        % Description
    end
    

    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            S = webread("http://climatedataapi.worldbank.org/climateweb/rest/v1/country/cru/tas/year/USA");
            app.Year = [S.year];
            app.Data = [S.data];
            plot(app.UIAxes,[S.year],[S.data])
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.SmoothedData = smooth(app.Data,changingValue);
            plot(app.UIAxes,app.Year,app.Data)
            hold(app.UIAxes,'on')
            plot(app.UIAxes,app.Year,app.SmoothedData)
            hold(app.UIAxes,'off')
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            assignin('base','smooethdData',app.SmoothedData)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
        end
    end

    methods (Access = public)

        % Construct app
        function app = testapp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end