classdef SegmentImage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ImageAxes                 matlab.ui.control.UIAxes
        HistAxes                  matlab.ui.control.UIAxes
        LoadImageButton           matlab.ui.control.Button
        SaveImageButton           matlab.ui.control.Button
        OverlayCheckBox           matlab.ui.control.CheckBox
        ChannelDropDownLabel      matlab.ui.control.Label
        ChannelDropDown           matlab.ui.control.DropDown
        Param1EditFieldLabel      matlab.ui.control.Label
        Param1EditField           matlab.ui.control.NumericEditField
        Param2EditFieldLabel      matlab.ui.control.Label
        Param2EditField           matlab.ui.control.NumericEditField
        Options1DropDownLabel     matlab.ui.control.Label
        Options1DropDown          matlab.ui.control.DropDown
        ScheduleListBoxLabel      matlab.ui.control.Label
        ScheduleListBox           matlab.ui.control.ListBox
        LoadButton                matlab.ui.control.Button
        RemoveButton              matlab.ui.control.Button
        AddButton                 matlab.ui.control.Button
        UpButton                  matlab.ui.control.Button
        DownButton                matlab.ui.control.Button
        Options2DropDownLabel     matlab.ui.control.Label
        Options2DropDown          matlab.ui.control.DropDown
        LoadMaskButton            matlab.ui.control.Button
        SelectImageDropDownLabel  matlab.ui.control.Label
        SelectImageDropDown       matlab.ui.control.DropDown
        ExportButton              matlab.ui.control.Button
        Slider_2                  matlab.ui.control.Slider
        Slider                    matlab.ui.control.Slider
    end

    
    properties (Access = private)
        imarray % The array of input images. If a single image is chosen then this is identical to rawimage.
        rawimage % Raw Image. The only callbacks that should change this are those that load it. 
        dispimage % The image (and any annotations) that are displayed in the GUI.
        impath % path to the image files.
        dispchann % display channel
    end
    
    methods (Access = private)
        
        function updateimage(app)
            if iscell(app.dispimage) % Display if image is an image array
                figure('Visible','off')
                montIm = montage(app.dispimage);
                app.dispimage=montIm.CData;
            end
            
            app.dispimage = array2im(app,app.dispimage);

            % set display channel
            switch app.dispchann
                case 'Red'
                    app.dispimage = app.rawimage(:,:,1);
                case 'Green'
                    app.dispimage = app.rawimage(:,:,2);
                case 'Blue'
                    app.dispimage = app.rawimage(:,:,3);
                case 'Gray'
                    if isRGB(app.rawimage)
                        app.dispimage = rgb2gray(app.rawimage);
                    end
                otherwise
                    app.dispimage = app.rawimage;
            end
            
            % Display the image
            imagesc(app.ImageAxes,app.dispimage)
        end
        
        function imgOut = array2im(app,arrayIn)
            % Convert an image montage to a single image, for display purposes. 
            if iscell(arrayIn) 
                figure('Visible','off')
                montIm = montage(arrayIn);
                imgOut=montIm.CData;
            elseif isa(arrayIn,'uint8')
                imgOut =arrayIn;
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure ImageAxes
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            app.rawimage = imread('hestain.png');
            app.dispimage = app.rawimage;
            app.dispchann = 'All';
            updateimage(app)
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [app.imarray,~,app.impath] = uigetimages;
            app.rawimage = array2im(app,app.imarray);
            app.dispimage = app.rawimage;
            updateimage(app)
        end

        % Value changed function: ChannelDropDown
        function ChannelDropDownValueChanged(app, event)
            app.dispchann = app.ChannelDropDown.Value;
            updateimage(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ImageAxes = uiaxes(app.UIFigure);
            title(app.ImageAxes, 'Title')
            xlabel(app.ImageAxes, 'X')
            ylabel(app.ImageAxes, 'Y')
            app.HistAxes = uiaxes(app.UIFigure);
            title(app.HistAxes, 'Title')
            xlabel(app.HistAxes, 'X')
            ylabel(app.HistAxes, 'Y')
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.SaveImageButton = uibutton(app.UIFigure, 'push');
            app.SaveImageButton.Text = 'Save Image';
            app.OverlayCheckBox = uicheckbox(app.UIFigure);
            app.OverlayCheckBox.Text = 'Overlay';
            app.ChannelDropDownLabel = uilabel(app.UIFigure);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.UIFigure);
            app.ChannelDropDown.Items = {'All', 'Gray', 'Red', 'Blue', 'Green'};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.ChannelDropDown.Value = 'All';
            app.Param1EditFieldLabel = uilabel(app.UIFigure);
            app.Param1EditFieldLabel.Text = 'Param 1';
            app.Param1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Slider = uislider(app.UIFigure);
            app.Slider_2 = uislider(app.UIFigure);
            app.Param2EditFieldLabel = uilabel(app.UIFigure);
            app.Param2EditFieldLabel.Text = 'Param 2';
            app.Param2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Options1DropDownLabel = uilabel(app.UIFigure);
            app.Options1DropDownLabel.Text = 'Options 1';
            app.Options1DropDown = uidropdown(app.UIFigure);
            app.ScheduleListBoxLabel = uilabel(app.UIFigure);
            app.ScheduleListBoxLabel.Text = 'Schedule';
            app.ScheduleListBox = uilistbox(app.UIFigure);
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.Text = 'Export';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.Text = 'Load';
            app.RemoveButton = uibutton(app.UIFigure, 'push');
            app.RemoveButton.Text = 'Remove';
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.Text = 'Add';
            app.UpButton = uibutton(app.UIFigure, 'push');
            app.UpButton.Text = '↓';
            app.DownButton = uibutton(app.UIFigure, 'push');
            app.DownButton.Text = '↑';
            app.Options2DropDownLabel = uilabel(app.UIFigure);
            app.Options2DropDownLabel.Text = 'Options 2';
            app.Options2DropDown = uidropdown(app.UIFigure);
            app.LoadMaskButton = uibutton(app.UIFigure, 'push');
            app.LoadMaskButton.Text = 'Load Mask';
            app.SelectImageDropDownLabel = uilabel(app.UIFigure);
            app.SelectImageDropDownLabel.Text = 'Select Image';
            app.SelectImageDropDown = uidropdown(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = SegmentImage

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end