classdef SegmentImageClean < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        OtherOperationsPanel          matlab.ui.container.Panel
        TrainNetworkButton            matlab.ui.control.Button
        ComponentImagesToMaskButton   matlab.ui.control.Button
        MaskToComponentImagesButton   matlab.ui.control.Button
        PolarityAndOrientationAnalysisButton  matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        HoldEndCheckBox               matlab.ui.control.CheckBox
        CurIDEditField                matlab.ui.control.NumericEditField
        CurIDEditFieldLabel           matlab.ui.control.Label
        pauseButton                   matlab.ui.control.Button
        ParmBox                       matlab.ui.control.ListBox
        UITable                       matlab.ui.control.Table
        OperationTree                 matlab.ui.container.Tree
        BasicOperationsNode           matlab.ui.container.TreeNode
        contrastNode                  matlab.ui.container.TreeNode
        denoiseNode                   matlab.ui.container.TreeNode
        flatfieldNode                 matlab.ui.container.TreeNode
        thresholdNode                 matlab.ui.container.TreeNode
        invertNode                    matlab.ui.container.TreeNode
        ConversionNode                matlab.ui.container.TreeNode
        channelNode_2                 matlab.ui.container.TreeNode
        binarizeNode_2                matlab.ui.container.TreeNode
        isolatechannNode              matlab.ui.container.TreeNode
        MorphologicalOperationsNode   matlab.ui.container.TreeNode
        erodeNode                     matlab.ui.container.TreeNode
        dilateNode                    matlab.ui.container.TreeNode
        closeNode                     matlab.ui.container.TreeNode
        openNode                      matlab.ui.container.TreeNode
        clearborderNode               matlab.ui.container.TreeNode
        watershedNode                 matlab.ui.container.TreeNode
        removeNode                    matlab.ui.container.TreeNode
        skelNode                      matlab.ui.container.TreeNode
        bothatNode                    matlab.ui.container.TreeNode
        tophatNode                    matlab.ui.container.TreeNode
        bridgeNode                    matlab.ui.container.TreeNode
        cleanNode                     matlab.ui.container.TreeNode
        thickenNode                   matlab.ui.container.TreeNode
        thinNode                      matlab.ui.container.TreeNode
        ellipsifyNode                 matlab.ui.container.TreeNode
        circularizeNode               matlab.ui.container.TreeNode
        ComponentPropertyFiltersNode  matlab.ui.container.TreeNode
        areaNode                      matlab.ui.container.TreeNode
        convexareaNode                matlab.ui.container.TreeNode
        eccentricityNode              matlab.ui.container.TreeNode
        equivdiameterNode             matlab.ui.container.TreeNode
        eulernumberNode               matlab.ui.container.TreeNode
        extentNode                    matlab.ui.container.TreeNode
        filledareaNode                matlab.ui.container.TreeNode
        majoraxislengthNode           matlab.ui.container.TreeNode
        minoraxislengthNode           matlab.ui.container.TreeNode
        orientationNode               matlab.ui.container.TreeNode
        perimeterNode                 matlab.ui.container.TreeNode
        circularityNode               matlab.ui.container.TreeNode
        numbwfiltNode                 matlab.ui.container.TreeNode
        solidityNode                  matlab.ui.container.TreeNode
        IntensityPropertyFiltersNode  matlab.ui.container.TreeNode
        maxintensityNode              matlab.ui.container.TreeNode
        minintensityNode              matlab.ui.container.TreeNode
        meanintensityNode             matlab.ui.container.TreeNode
        MaskOperationsNode            matlab.ui.container.TreeNode
        maskNode                      matlab.ui.container.TreeNode
        asmaskNode                    matlab.ui.container.TreeNode
        cropbymaskNode                matlab.ui.container.TreeNode
        neighborthreshNode            matlab.ui.container.TreeNode
        SelImDD                       matlab.ui.control.DropDown
        SelImDDLabel                  matlab.ui.control.Label
        LoadMaskButton                matlab.ui.control.Button
        OptDD2                        matlab.ui.control.DropDown
        OptDD2Label                   matlab.ui.control.Label
        DownButton                    matlab.ui.control.Button
        UpButton                      matlab.ui.control.Button
        AddButton                     matlab.ui.control.Button
        RemoveButton                  matlab.ui.control.Button
        LoadButton                    matlab.ui.control.Button
        ExportButton                  matlab.ui.control.Button
        ScheduleListBox               matlab.ui.control.ListBox
        ScheduleListBoxLabel          matlab.ui.control.Label
        OptDD1                        matlab.ui.control.DropDown
        OptDD1Label                   matlab.ui.control.Label
        Edit2                         matlab.ui.control.NumericEditField
        Slider2Label                  matlab.ui.control.Label
        Slider2                       matlab.ui.control.Slider
        Slider1                       matlab.ui.control.Slider
        Edit1                         matlab.ui.control.NumericEditField
        Slider1Label                  matlab.ui.control.Label
        ChannelDropDown               matlab.ui.control.DropDown
        ChannelDropDownLabel          matlab.ui.control.Label
        OverlayCheckBox               matlab.ui.control.CheckBox
        SaveImageButton               matlab.ui.control.Button
        LoadImageButton               matlab.ui.control.Button
        HistAxes                      matlab.ui.control.UIAxes
        ImageAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        imarray % The array of input images. If a single image is chosen then this is identical to rawimage.
        rawimage % Raw Image. The only callbacks that should change this are those that load it. 
        dispimage % The image (and any annotations) that are displayed in the GUI.
        impath % path to the image files.
        dispchann % display channel
        seloper % currently selected operation in operation tree. 
        scheditem % currently selected item in the schedule list. 
        scheditemID % Index of currently selected item in the schedule list. 

        schedule % The segmentation schedule. Contains operation, operation parameters, handles to the manipulators that control parameters, and appropriate names for these.
        schedcolnames % Description
        imRez % Image Resolution
        
        defmask % Default mask
        defimage % Default processing image. 
        morphFiltOpns % Description
        numIms % Number of Images in the input array
        isimport % id that indicates whether the schedule was imported. 
    end
    
    methods (Access = private)
        
        function updateimage(app)
            
            % Force displayed image to be the last in imarray if hold end check box is checked. 
            if app.HoldEndCheckBox.Value==1
                app.dispimage=imtile(app.imarray(:,end));
            else 
                app.dispimage = imtile(app.imarray(:,app.scheditemID+1));
            end
            

            % set display channel
            switch app.OverlayCheckBox.Value
                case false
                    if isRGB(app.dispimage)
                        switch app.ChannelDropDown.Value
                            case 'Red'
                                app.dispimage = app.dispimage(:,:,1);
                            case 'Green'
                                app.dispimage = app.dispimage(:,:,2);
                            case 'Blue'
                                app.dispimage = app.dispimage(:,:,3);
                            case 'Gray'
                                app.dispimage = rgb2gray(app.dispimage);
                               
                            otherwise
                                app.dispimage = app.dispimage;
                        end
                    end
                    
                     % Display the image
                    imagesc(app.ImageAxes,app.dispimage)
                case true
                    if isRGB(app.dispimage)
                        overlay = rgb2gray(app.dispimage);
                    else
                        overlay = app.dispimage;
                    end
                    app.dispimage = labeloverlay(rgb2gray(imtile(app.rawimage)),overlay);
                    imagesc(app.ImageAxes,app.dispimage);
                    
            end
            a=1;
           % Update histogram
%            PlotHistogram(app)
            
        end
        
        function imgOut = array2im(app,arrayIn)
            % Convert an image montage to a single image, for display purposes. 
            if iscell(arrayIn)&&length(arrayIn)>1
%                 figure('Visible','off')
%                 montIm = montage(arrayIn);
                imgOut=imtile(arrayIn);
            elseif iscell(arrayIn)&&length(arrayIn)==1
                imgOut = arrayIn{1};
            elseif isa(arrayIn,'uint8')||islogical(arrayIn)
                imgOut =arrayIn;
            end
        end
        
        function array = shiftelem(app,id,array,shift) % shift elements in an array up or down
%             [~,id] = ismember(elem,array);
%             mover = array(id,:);
            [nrow,~] = size(array);
            
            if strcmp(shift,'up')&&id>1
                mover = array(id,:);
                moved = array(id-1,:);
                array(id-1,:) = mover;
                array(id,:) = moved;
            elseif strcmp(shift,'down')&&id<nrow
                mover = array(id,:);
                moved = array(id+1,:);
                array(id+1,:) = mover;
                array(id,:) = moved;                
            end
            
            
        end
        
        function enableparams(app)
            curitem = app.ScheduleListBox.Value;
            curID = find(strcmp(app.schedule.Oper,curitem));
            app.CurIDEditField.Value = curID; % temp for decoding.
            
            % by default disable all params
            disableAllParams(app)
            
            
            Params = app.schedule.Params{curID};
            Handles = app.schedule.Handles{curID};
            Names = app.schedule.Names{curID};
            Options = app.schedule.Options{curID};
            nparams = length(Params);
            
            if nparams~=0 % Don't change anything for operations that require no additional input.
                for n = 1:nparams % iterate through each of the parameters for the operation and update the ui items. 
                    app.(Handles{n}).Enable = 'on';
                    app.([Handles{n},'Label']).Text = Names{n};
                    
                    if iscell(Options{n}) % its a list
                        app.(Handles{n}).Items = Options{n};
                    elseif isnumeric(Options{n}) % its a range
                        app.(Handles{n}).Limits = Options{n};
                    else
                        error('The options for the operation are probably invalid')
                    end
                    
                    try
                        app.(Handles{n}).Value = Params{n};
                    catch % Convert img reference ids back to the img text.
                        try
                        app.(Handles{n}).Value = app.ScheduleListBox.Items{Params{n}};
                        catch
                        end
                    end
                end
            end  
           
                % Make the edit boxes match the sliders if they are enabled.
                if strcmp(app.Slider1.Enable,'on')
                    app.Edit1.Enable ='on';
                    app.Edit1.Limits = app.Slider1.Limits;
                    app.Edit1.Value = app.Slider1.Value;
                    
                end
                
                if strcmp(app.Slider2.Enable,'on')
                    app.Edit2.Enable ='on';
                    app.Edit2.Limits = app.Slider2.Limits;
                    app.Edit2.Value = app.Slider2.Value;
                    
                end
                
                % If the select image dropdown is enabled, also enable the load mask button
                if strcmp(app.SelImDD.Enable,'on')
                    app.LoadMaskButton.Enable = 'on';
                end
                
                % update available fields in image dropdown
                app.SelImDD.Items = ['Raw' app.ScheduleListBox.Items];

        end

        
        function addDefault(app,oper,replace) % Specify defaults and display params for each operation
            curOper = erase(oper,'*'); % remove any asterisk from the operation name.
            switch curOper
                case 'contrast'
                    if strcmp(app.OptDD1.Value,'global')
                        params = {'global'}; % Define the Default value for the manipulator who's handle is listed below. 
                        handles = {'OptDD1'}; % This is the handle of the manipulator that will be used to specify the parameters of the operation.
                        names = {'Type'}; % Name the manipulator. This helps the user interpret the interface. 
                        options = {{'global','local'}}; % Specify options for the manipulator. For char based operators e.g. dropdowns, this is the
                        % set of valid entries for the operator. For numeric based operators e.g. sliders, this is usually the the range of valid
                        % numerics specified as a two-element vector e.g. [min max]. 
                        
                    else 
                        params = {'local',0.3,0.25}; % When more than one manipulator is needed. Specify the default values for each manipulator.
                        handles = {'OptDD1','Slider1','Slider2'}; % the handles for each manipulator
                        names = {'Type','Edge Threshold','Amount'}; % the labels/names for each manipulator
                        options = {{'global','local'},[0 1],[-1 1]}; % and the valid entries or options for each manipulator. 
                    end
                    
                    histdata = 'pixel'; % Specify what kind of data is shown in the histogram if any at all. 
                    
                    % repeat this for each set of operations that require unique manipulators. Some operations can use the same scheme. 
                    % We use an if/else statement when the users choice of a manipulator effects what manipulators are needed. Store the default
                    % within the else statement and the unique scheme in the if statement. For example, by default the contrast operation uses 
                    % local contrasting and the parameters associated with it. Edge Threshold and Amount. However, if the user sets the type to global
                    % the manipulator scheme changes. 
                case 'denoise'
                    params = {1};
                    handles = {'Slider1'};
                    names = {'Blur'};
                    options = {[1 100]};
                    
                    histdata = 'pixel';
                    
                case 'flatfield'
                    maxlim = ceil(min(mean(app.imRez,1)));
                    params = {maxlim/2};
                    handles = {'Slider1'};
                    names = {'Sigma'};
                    options = {[1 maxlim]};
                    
                    histdata = 'pixel';
                    
                case 'threshold'
                    params = {0,255};
                    handles = {'Slider1','Slider2'};
                    names = {'Min','Max'};
                    options ={[0 255],[0 255]};
                    
                    histdata = 'pixel';
                    
                case 'binarize'
                    if strcmp(app.OptDD1.Value,'adaptive')
                        params = {'adaptive'};
                        handles = {'OptDD1'};
                        names = {'Method'};
                        options ={{'global','adaptive'}};
                    else strcmp(app.OptDD1.Value,'global')
                        params = {'global',0.5};
                        handles = {'OptDD1','Slider1'};
                        names = {'Method','Threshold'};
                        options ={{'global','adaptive'},[0 1]};
                    end
                    
                    histdata = 'pixel';
                    
                case 'channel'
                    params = {'red'};
                    handles = {'OptDD2'};
                    names = {'Channel'};
                    options = {{'red','green','blue','gray'}};
                    
                    histdata = 'pixel';
                    
                case 'isolatechann'
                    params = {'red'};
                    handles = {'OptDD2'};
                    names = {'Channel'};
                    options = {{'red','green','blue'}};
                    
                    histdata = 'pixel';
                    
                case {'erode','dilate'}
                    params = {1};
                    handles = {'Slider1'};
                    names = {'Radius'};
                    options = {[1 20]};
                    
                    histdata = 'solidity';
                    
                case {'close','open'}
                    params = {1,'false'};
                    handles = {'Slider1','OptDD2'};
                    names = {'Radius','By Component'};
                    options = {[1 20],{'true','false'}};
                    
                    histdata = 'solidity';
                
                case app.morphFiltOpns
                    % compute regionprops here then use them to set limits and whatnot
                    [morphprops,morphmin,morphmax,morphdef,morphN] = getMorphOps(app,curOper);
                    if ismember(app.OptDD1.Value,{'botn','topn'})
                        params = {app.OptDD1.Value,5};
                        handles = {'OptDD1','Slider1'};
                        names = {'Filter Type','Number'};
                        options = {{'range','topn','botn'},[1 ceil(morphN*0.1)]};
                    else
                        params = {'range',morphmin,morphmax};
                        handles = {'OptDD1','Slider1','Slider2'};
                        names = {'Filter Type','Min','Max'};
                        options = {{'range','topn','botn'},[morphmin morphmax],[morphmin morphmax]};
                    end
                    
                    histdata = curOper;
                
                case {'maxintensity','minintensity','meanintensity'}
                    
                    if ismember(app.OptDD1.Value,{'botn','topn'})
                        params = {'Raw','red',app.OptDD1.Value,10};
                        handles = {'SelImDD','OptDD2','OptDD1','Slider1'};
                        names = {'Target Image','Channel','Filter Type','Number'};
                        options = {['Raw' app.ScheduleListBox.Items],{'red','green','blue','all'},{'range','topn','botn'},[0 100]};
                    else
                        params = {'Raw','red','range',0,255};
                        handles = {'SelImDD','OptDD2','OptDD1','Slider1','Slider2'};
                        names = {'Target Image','Channel','Filter Type','Min','Max'};
                        options = {['Raw' app.ScheduleListBox.Items],{'red','green','blue','all'},{'range','topn','botn'},[0 255],[0 255]};
                    end
                    
                    histdata = curOper;
                
                case {'mask'}
                    params = {'Raw','false'};
                    handles = {'SelImDD','OptDD2'};
                    names = {'Select Mask','Invert'};
                    options = {['Raw' app.ScheduleListBox.Items],{'true','false'}};
                    
                    histdata = 'pixel';
                
                case {'asmask'}
                    params = {'Raw','false'};
                    handles = {'SelImDD','OptDD2'};
                    names = {'Select Image','Invert'};
                    options = {['Raw' app.ScheduleListBox.Items],{'true','false'}};
                    
                    histdata = 'pixel';
                
                case {'cropbymask'}
                    if strcmp('Raw','true')
                        params = {app.seloper,app.OptDD2.Value,'true'};
                    else
                    params = {app.seloper,'false','false'};
                    end
                    handles = {'SelImDD','OptDD2','OptDD1'};
                    names = {'Select Image','Invert','Hard'};
                    options = {['Raw' app.ScheduleListBox.Items],{'true','false'},{'true','false'}};
                    
                    histdata = 'pixel';
                
                case {'neighborthresh'}
                    params = {'Raw',1,'false'};
                    handles = {'SelImDD','Slider1','OptDD2'};
                    names = {'Select Mask','Distance','Invert'};
                    options = {['Raw' app.ScheduleListBox.Items],[1 50],{'true','false'}};
                    
                    histdata = 'pixel';
                
                otherwise
                    
                    params = {};
                    handles = {};
                    names = {};
                    options = {};
                    histdata = 'none';
            end
            
            % Convert string image references to IDs before storing in schedule. 
            if ismember(oper,{'neighborthresh','cropbymask','asmask','mask','maxintensity','minintensity','meanintensity'})
                if strcmpi(params{1},'Raw')||strcmpi(params{1},'None')
                    params{1} = 0;
                else
                    [~,params{1}] = ismember(params{1},app.ScheduleListBox.Items);
                end
            end
            
            tempTab = cell2table({oper,{params},{handles},{names},{options},histdata});
            tempTab.Properties.VariableNames = app.schedcolnames;
            
            if replace % Replaces the current entry with the default
                app.schedule(app.scheditemID,:) = tempTab;
            else % appends/inserts the default to the schedule list.
                app.schedule = insertItem(app,app.schedule,tempTab,app.scheditemID);
            end
            app.UITable.Data =app.schedule(:,1:2); % update the data displayed in the table
        end
        
        function array = insertItem(app,array,insert,n) % insert a new row into the array at row n. 
            [nrow,~] = size(array);
            if n<=nrow
                top = array(1:n-1,:);
                bottom = array(n:end,:);
                array = [top;insert;bottom];
            else
                array = [array;insert];
            end
        end
        
        function results = updateSchedule(app,curHandle,varargin) % update the schedule when a manipulator is changed. Place in all callbacks that should change the schedule.
            %% Schedule Update
            
            callHandles = app.schedule.Handles{app.scheditemID}; % Handles of callbacks that the current schedule item has links to.
            if strcmp(curHandle,'Edit1') % Treat Edit1 handles like slider1 handles
                [~,parmID] = ismember('Slider1',callHandles);
            elseif strcmp(curHandle,'Edit2') % Treat Edit2 handles like slider2 handles
                [~,parmID] = ismember('Slider2',callHandles);
            elseif strcmp(curHandle,'LoadMaskButton') % Special case for loading external images with Load Mask Button. Reference images should always be listed first in the parameter list for a given operation
                parmID = 1;
            else
                [~,parmID] = ismember(curHandle,callHandles); % get index of parameter connected to curHandles. 
            end
            
            if ~isempty(varargin)
                curValue = {varargin{1}};
            else
                curValue = {app.(curHandle).Value};
            end
            
            if ischar(curValue{1})&&ismember(curValue,app.ScheduleListBox.Items) % the value is an image reference that needs to be converted to an ID.
                [~,imID] = ismember(curValue,app.ScheduleListBox.Items);
                curValue = {imID};
            end
            
            app.schedule.Params{app.scheditemID}(parmID) = curValue;
            
            % Segment then update the images
            OperateAndUpdateIms(app)
        end
        
        function PerformOpers(app)
            curID = app.scheditemID;
            
            SegT = app.schedule(curID:end,1:2); % only need part of the segmentation schedule
            
            % remove asterisks from operation names
            SegT.Oper = erase(SegT.Oper,'*');  
            
            % handle instance where schedule is imported and a new mask must be selected. 
            if app.isimport
                maskID = find(strcmp(SegT.Oper,"mask"));
                for k = 1:length(maskID)
                    if ischar(SegT.Params{maskID(k)}{1})
                        mb = msgbox(sprintf('Schedule Imported. Please select new mask for operation %d',maskID));
                        [~,newfiles,newpath] = uigetimages;
                        close(mb)
                        newmaskdir = [newpath newfiles{1}];
                        
                        SegT.Params{maskID(k)}{1}=newmaskdir;
                    end
                end
                app.isimport = false;
            end
              
            [~,SegIms]=SegmentBySchedule('RawImage',app.imarray(:,curID),'Schedule',SegT,'ImArray',app.imarray);
            SegIms = SegIms';
            [~,nims] = size(SegIms);
            app.imarray(:,(curID+1):(curID+nims-1)) = SegIms(:,2:end);
            
        end
        
        function OperateAndUpdateIms(app)
            %% Call to Segment By Schedule. 
            PerformOpers(app)
           
            %% Update display image
            app.dispimage = app.imarray{app.scheditemID+1};
            updateimage(app)
        end
        
        function disableAllParams(app)
            % Disable all parameter manipulators
            app.OptDD1.Enable = 'off';
            app.OptDD2.Enable = 'off';
            app.Edit1.Enable = 'off';
            app.Edit2.Enable = 'off';
            app.Slider1.Enable = 'off';
            app.Slider2.Enable = 'off';
            app.LoadMaskButton.Enable = 'off';
            app.SelImDD.Enable = 'off';
            app.Slider1Label.Text = '';
            app.Slider2Label.Text = '';
        end
        
        function [morphprops,morphmin,morphmax,morphdef,morphN] = getMorphOps(app,oper)
            bwIms = app.imarray(:,app.scheditemID); % image to set limits on morphological filters.
            morphprops = [];
            for m=1:app.numIms
                if strcmp(oper,'circularity')
                    morphprops = [morphprops; bwcirc(bwIms{m})];
                elseif strcmp(oper,'numbwfilt')
                    morphprops = [morphprops; numBWComps(bwIms{m})];
                else
                    morphprops= [morphprops; table2array(regionprops('table',bwIms{m},oper))]; % vector of morphological properties of the components in bwIm.
                end
            end
            morphmin = min(morphprops); % min value for sliders
            
            if strcmp(oper,'numbwfilt')
                morphmin=0;
            end
            
            morphmax = max(morphprops); % max value for sliders
            morphdef = mean(morphprops); % default value
            morphN = length(morphprops);
        end
        
        function PlotHistogram(app)
            lwd = 2; % linewidth
            
            % Get current image.
            curID = app.scheditemID;
            curImg  = app.imarray(:,curID+1);
            
            %% Get relevant data and convert to frequencies
            [curx,curfreq,datatype,xlims] = GetHistData(app,curImg,curID);
            
            cla(app.HistAxes); % clear anything from previous plot
            if ~strcmp(datatype,"none") % do nothing if nothing is specified
                % plot previous data but only if its not the first step;          
                %% Plotting
                hold(app.HistAxes,'on')
                
                
                % perform identical operation on previous image for reference
                if curID>0
                    prevImg = app.imarray{curID};
                    [prex,prefreq,~,xlims] = GetHistData(app,prevImg,curID);
                    
                    if length(prefreq)==3
                        cols = {'R','G','B'}; % color options
                    else
                        cols = {'K'};
                    end
                    
                    for nchan = 1:length(prefreq)
                    plot(app.HistAxes,prex{nchan},prefreq{nchan},'Color',cols{nchan},'LineStyle','--','LineWidth',lwd/2);
                    end
                    
                end
                
                %% Current image histogram
                if length(curfreq)==3
                cols = {'R','G','B'}; % color options
                else
                    cols = {'K'};
                end
                              
                for nchan = 1:length(curfreq)
                    plot(app.HistAxes,curx{nchan},curfreq{nchan},'Color',cols{nchan},'LineWidth',lwd);
                end
                
                if ~isempty(xlims)
                    if diff(xlims)~=0
                        app.HistAxes.XLim = xlims;
                    end
                end
                
                if strcmp(datatype,'pixel')
                    xlab = 'Pixel Intensity';
                    ylab = 'Frequency (excluding 0s)';
                else
                    xlab = datatype;
                    ylab = 'Frequency';
                end
                
                xlabel(app.HistAxes,xlab);
                ylabel(app.HistAxes,ylab);
                hold(app.HistAxes,'off');
            end
            
            
        end
        
        function type = getimagetype(app,im)
            if isBW(im)
                type = 'bw';
            elseif isRGB(im)
                type = 'rgb';
            elseif isGray(im)
                type = 'gray';
            else
                type= 'none';
            end
        end
        
        function [x,freq,datatype,xlims] = GetHistData(app,curImg,curID)
            imtype = getimagetype(app,curImg{1}); % get image type, the column should have an identical type.
            
            if curID==0 % special case on startup
                datatype = 'pixel';
            else
                datatype = app.schedule.HistData(curID); 
            end
            
            if strcmp(datatype,"none") % skip if the type is none. 
                x = {};
                freq = {};
                
                xlims = [];
                return
            end
            
            rgbbins = linspace(0,255,52);
            bwbins = linspace(0,1,3);
            
            % Extract the correct data from the image based upon the combination of the image type and data type.
            switch imtype
                case 'rgb'
                    switch datatype
                        case 'pixel'
                            npix = numel(curImg)/3;
                            for nchan=1:3
                                datv = reshape(curImg(:,:,nchan),[1 npix]);
                                [freq{nchan},x{nchan}] = FreqPlot(datv,'nbins',rgbbins,'omit',0);
                            end
                    end
                    xlims = [1 255];
                    
                    
                case 'gray'
                    switch datatype
                        case 'pixel'
                            datv = curImg(:);
                            [freq{1},x{1}] = FreqPlot(datv,'nbins',rgbbins,'omit',0);
                    end
                    xlims = [1 255];
                                        
                case 'bw'
                    switch datatype
                        case 'pixel'
                            maskedim = uint8(double(curImg).*double(app.imarray{curID}));
                            if isRGB(maskedim)
                                npix = numel(maskedim)/3;
                                for nchan=1:3
                                    datv = reshape(maskedim(:,:,nchan),[1 npix]);
                                    [freq{nchan},x{nchan}] = FreqPlot(datv,'nbins',rgbbins,'omit',0);
                                end
                                xlims = [1 255];
                            elseif isGray(maskedim)
                                datv = maskedim(:);
                                [freq{1},x{1}] = FreqPlot(datv,'nbins',rgbbins,'omit',0);
                                xlims = [1 255];
                            else
                                datv = maskedim(:);
                                [freq{1},x{1}] = FreqPlot(datv,'nbins',bwbins);
                                xlims = [0 1];
                            end
                            
                        case app.morphFiltOpns
                            props = regionprops('table',curImg,datatype);
                            datv = table2array(props);
                            [freq{1},x{1}] = FreqPlot(datv','interp',3);
                            xlims = [min(datv) max(datv)];
                    end                   
            end
            
            if ~exist('freq','var')
                datatype = "none";
                x = {};
                freq = {};
                xlims = [];
            end
            
        end
   
        
        function imRez = getImRez(app,arr)            
            imRez = cell2mat(cellfun(@size,arr,'UniformOutput',false));
            imRez(:,3) = [];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure ImageAxes
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            heimage = imread('hestain.png');
            app.rawimage = {heimage; imresize(heimage,0.5);imresize(heimage,0.25)};
            app.imarray = app.rawimage;
            app.dispimage = app.rawimage{1};
            app.dispchann = 'All';
            app.scheditemID = 0;
            
            app.isimport = false; % By default the schedule is not imported. 
            
            % Initialize Schedule
            initsched = table('size',[0,6],'VariableTypes',{'string','cell','cell','cell','cell','string'});
            app.schedcolnames = {'Oper','Params','Handles','Names','Options','HistData'};
            initsched.Properties.VariableNames = app.schedcolnames;
            app.schedule = initsched;
%             app.scheditemID = 0;
            app.CurIDEditField.Value = app.scheditemID;
            
%             [app.imRez(1),app.imRez(2)] = size(app.rawimage);
            app.imRez = getImRez(app,app.rawimage);
            app.numIms = length(app.imarray); % no. of images;
            
            updateimage(app) % display default images
            
            % default images and masks
%             app.defmask = true(app.imRez(1),app.imRez(2));
%             app.defimage = uint8(255.*ones(app.imRez(1),app.imRez(2)));
            
            % set the list of useable morphological filters
            app.morphFiltOpns = {'area','convexarea','eccentricity','equivdiameter','eulernumber',...
                'extent','filledarea','majoraxislength','minoraxislength','orientation',...
                'perimeter','circularity','numbwfilt','solidity'};
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [readim,~,app.impath] = uigetimages;
%             app.rawimage = array2im(app,readim);
            app.rawimage = readim;
            app.dispimage = imtile(app.rawimage);
            app.imarray = app.rawimage;
            app.numIms = length(app.imarray); % no. of images;
            app.imRez = getImRez(app,app.rawimage);
            
            % default images and masks
%             app.defmask = true(app.imRez(1),app.imRez(2));
%             app.defimage = uint8(255.*ones(app.imRez(1),app.imRez(2)));
            
            updateimage(app)
        end

        % Value changed function: ChannelDropDown
        function ChannelDropDownValueChanged(app, event)
            app.dispchann = app.ChannelDropDown.Value;
            updateimage(app)
        end

        % Value changed function: ScheduleListBox
        function ScheduleListBoxValueChanged(app, event)
            app.scheditem = app.ScheduleListBox.Value;
            [~,app.scheditemID] = ismember(app.scheditem,app.ScheduleListBox.Items);
            enableparams(app)
            
            app.dispimage = app.imarray{app.scheditemID+1};
            updateimage(app)
        end

        % Selection changed function: OperationTree
        function OperationTreeSelectionChanged(app, event)
            app.seloper = app.OperationTree.SelectedNodes.Text;
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            % append the currently selected operation in the operation tree to the segmentation schedule in the schedule listbox.
            % Do nothing if the currently selected node is a parent node or no operation is selected.
%             try
                app.scheditemID = app.scheditemID+1;
                
                if ~isempty(app.seloper)&&~ismember(app.seloper,{app.OperationTree.Children.Text})
                    
                    % check if the selected operator is already a member of the listbox. If it is, add a star to the name so that it
                    % can be uniquely identified later. 
                    while ismember(app.seloper,app.ScheduleListBox.Items)
                        app.seloper = [app.seloper '*'];
                    end
    %                 app.ScheduleListBox.Items = [app.ScheduleListBox.Items app.seloper];
                    
                    app.ScheduleListBox.Items = insertItem(app,app.ScheduleListBox.Items',app.seloper,app.scheditemID);
                    
                    % Add the default parameters for the new operation to the schedule.
                    addDefault(app,app.seloper,false)
                end
                
                % Set the current schedule id to the length of the schedule. 
                app.scheditem = app.seloper;
    %             app.scheditemID = length(app.ScheduleListBox.Items);
                
                app.ScheduleListBox.Value = app.seloper;
                
                % get defaults for current operator. 
                
                enableparams(app)
                
                % Segment and update images
                OperateAndUpdateIms(app)
%             catch
%             end
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            % Remove the currently selected item from the schedule list. 
            app.ScheduleListBox.Items(app.scheditemID)=[];
            
            % Remove the corresponding entry from the schedule
            app.schedule(app.scheditemID,:)=[];
            
            app.UITable.Data = app.schedule(:,1:2); % update the data displayed in the table
            
            % Remove the corresponding image in imarray.
            remID=app.scheditemID+1;
            app.imarray(:,remID)=[]; % plus one because the raw image is the first entry in imarray
            
            nscheditems = length(app.ScheduleListBox.Items);
            
            % set schedule item ID and item to 1, because this is the default behavior of the listbox.
            
            if nscheditems==0
                app.scheditem = {};
                app.scheditemID = [];
            elseif app.scheditemID>nscheditems
                app.scheditemID = nscheditems;
                app.scheditem = app.ScheduleListBox.Items(app.scheditemID);
            else
                app.scheditemID = app.scheditemID;
                app.scheditem = app.ScheduleListBox.Items(app.scheditemID);
            end
            app.ScheduleListBox.Value = app.scheditem;
            
            % set how the parameter operators should respond to the removal of items from the list. 
            if nscheditems==0
                disableAllParams(app)
            else
                enableparams(app)
            end
            
            if nscheditems==0
               app.dispimage = app.rawimage;
               updateimage(app)
            else
                OperateAndUpdateIms(app)
            end
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
             app.ScheduleListBox.Items = shiftelem(app,app.scheditemID,app.ScheduleListBox.Items','down');
             app.schedule = shiftelem(app,app.scheditemID,app.schedule,'down');
             app.scheditemID = app.scheditemID+1;
             if app.scheditemID>length(app.ScheduleListBox.Items)
                 app.scheditemID=length(app.ScheduleListBox.Items);
             end
             
             app.UITable.Data =app.schedule(:,1:2); % update the data displayed in the table
             enableparams(app)
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            app.ScheduleListBox.Items = shiftelem(app,app.scheditemID,app.ScheduleListBox.Items','up');
            app.schedule = shiftelem(app,app.scheditemID,app.schedule,'up');
            app.scheditemID = app.scheditemID-1;
            if app.scheditemID<1
                app.scheditemID=1;
            end
            
            app.UITable.Data =app.schedule(:,1:2); % update the data displayed in the table
            enableparams(app)
        end

        % Value changed function: OptDD1
        function OptDD1ValueChanged(app, event)
            value = app.OptDD1.Value;
            addDefault(app,app.scheditem,true)
            enableparams(app)
            updateSchedule(app,'OptDD1',value)
        end

        % Value changing function: Slider1
        function Slider1ValueChanging(app, event)
            changingValue = event.Value;
            app.Edit1.Value = event.Value;
            
            updateSchedule(app,'Slider1',event.Value)
        end

        % Value changing function: Slider2
        function Slider2ValueChanging(app, event)
            changingValue = event.Value;
            app.Edit2.Value = event.Value;
            
            updateSchedule(app,'Slider2',event.Value)
        end

        % Value changed function: Edit1
        function Edit1ValueChanged(app, event)
            value = app.Edit1.Value;
            app.Slider1.Value = value;
            updateSchedule(app,'Edit1',value)
        end

        % Value changed function: Edit2
        function Edit2ValueChanged(app, event)
            value = app.Edit2.Value;
            app.Slider2.Value = value;
            updateSchedule(app,'Edit2',value)
        end

        % Value changed function: SelImDD
        function SelImDDValueChanged(app, event)
            value = app.SelImDD.Value;
            updateSchedule(app,'SelImDD',value)
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            parms = app.schedule.Params{indices(1)};
            strparms = cellfun(@num2str,parms,'un',0); % convert all entries to strings
            app.ParmBox.Items = strparms;
        end

        % Value changed function: OptDD2
        function OptDD2ValueChanged(app, event)
            value = app.OptDD2.Value;
            updateSchedule(app,'OptDD2',value)
        end

        % Button pushed function: LoadMaskButton
        function LoadMaskButtonPushed(app, event)
            try
            [~,maskfiles,maskdir] = uigetimages;
            maskpath = fullfile(maskdir,maskfiles{1});
            updateSchedule(app,'LoadMaskButton',maskpath);
            catch
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
             SegT = app.schedule;
             ImArray = app.imarray;
             DispImage = app.dispimage;
             uisave({'SegT','ImArray','DispImage'})
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [schedfile,schedpath] = uigetfile('*.mat');
%             load(fullfile(schedpath,schedfile),'SegT','DispImage','ImArray');
            load(fullfile(schedpath,schedfile),'SegT');
            app.schedule = SegT;
            app.scheditem = SegT.Oper{end};
            app.scheditemID = 1;
            app.ScheduleListBox.Items = SegT.Oper;
            app.ScheduleListBox.Value = app.scheditem;
            app.imarray = app.rawimage;
            app.isimport = true;
            
            app.UITable.Data =app.schedule(:,1:2);
            enableparams(app)
            OperateAndUpdateIms(app)
        end

        % Button pushed function: pauseButton
        function pauseButtonPushed(app, event)
            a=1;
        end

        % Value changed function: OverlayCheckBox
        function OverlayCheckBoxValueChanged(app, event)
            value = app.OverlayCheckBox.Value;
            updateimage(app)
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)

            [filepath,~,ucancel] = uiputfile({'*.bmp','*.png'},'Save Image');
            
            if ucancel==0
            imwrite(app.dispimage,filepath);
            end
        end

        % Value changed function: HoldEndCheckBox
        function HoldEndCheckBoxValueChanged(app, event)
            updateimage(app)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Make current instance of app invisible
            app.UIFigure.Visible = 'off';
            
            % Open 2nd instance of app
            SegmentImage();  % <--------------The name of your app
            % Delete old instance
            close(app.UIFigure) %Thanks to Guillaume for suggesting to use close() rather than delete()
        end

        % Button pushed function: 
        % PolarityAndOrientationAnalysisButton
        function PolarityAndOrientationAnalysisButtonPushed(app, event)
            VestibularSystemAnalysis_V3
        end

        % Button pushed function: MaskToComponentImagesButton
        function MaskToComponentImagesButtonPushed(app, event)
            prompt = {'Set number of Samples. Press cancel to continue without sampling.'};
            title = 'Input';
            dims = 4;
            definput = {'0'};
            answer = inputdlg(prompt,title,dims,definput);
            
            if isempty(answer)
                ConvertToCompIms; 
            else 
                num = str2double(answer{1});
                
                if num <=0
                    ConvertToCompIms;
                else
                    ConvertToCompIms(num);
                end
            end
            
        end

        % Button pushed function: ComponentImagesToMaskButton
        function ComponentImagesToMaskButtonPushed(app, event)
            RebuildImFromComps;
        end

        % Button pushed function: TrainNetworkButton
        function TrainNetworkButtonPushed(app, event)
            TrainSegResNet;
        end

        % Callback function
        function SegmentByNetworkButtonPushed(app, event)
            % Deprecated, move this operation to SegmentBySchedule
%             SegByNet;
%             
%             clear app.imarray
%             
%             app.rawimage = imraw;
%             app.dispimage = RLabel;
%             
%             app.scheditemID = 1;
%             
%             app.imarray{1,1} = imraw;
%             app.imarray{app.scheditemID,2} = RLabel;
%             
%             app.dispimage = app.imarray{app.scheditemID+1};
%             updateimage(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ImageAxes = uiaxes(app.UIFigure);
            title(app.ImageAxes, 'Title')
            xlabel(app.ImageAxes, 'X')
            ylabel(app.ImageAxes, 'Y')
            app.HistAxes = uiaxes(app.UIFigure);
            title(app.HistAxes, 'Title')
            xlabel(app.HistAxes, 'X')
            ylabel(app.HistAxes, 'Y')
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.SaveImageButton = uibutton(app.UIFigure, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            app.OverlayCheckBox = uicheckbox(app.UIFigure);
            app.OverlayCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverlayCheckBoxValueChanged, true);
            app.OverlayCheckBox.Text = 'Overlay';
            app.ChannelDropDownLabel = uilabel(app.UIFigure);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.UIFigure);
            app.ChannelDropDown.Items = {'All', 'Gray', 'Red', 'Blue', 'Green'};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.ChannelDropDown.Value = 'All';
            app.Slider1Label = uilabel(app.UIFigure);
            app.Slider1Label.Text = 'Param 1';
            app.Edit1 = uieditfield(app.UIFigure, 'numeric');
            app.Edit1.ValueChangedFcn = createCallbackFcn(app, @Edit1ValueChanged, true);
            app.Edit1.Enable = 'off';
            app.Slider1 = uislider(app.UIFigure);
            app.Slider1.ValueChangingFcn = createCallbackFcn(app, @Slider1ValueChanging, true);
            app.Slider1.Enable = 'off';
            app.Slider2 = uislider(app.UIFigure);
            app.Slider2.ValueChangingFcn = createCallbackFcn(app, @Slider2ValueChanging, true);
            app.Slider2.Enable = 'off';
            app.Slider2Label = uilabel(app.UIFigure);
            app.Slider2Label.Text = 'Param 2';
            app.Edit2 = uieditfield(app.UIFigure, 'numeric');
            app.Edit2.ValueChangedFcn = createCallbackFcn(app, @Edit2ValueChanged, true);
            app.Edit2.Enable = 'off';
            app.OptDD1Label = uilabel(app.UIFigure);
            app.OptDD1Label.Text = 'Options 1';
            app.OptDD1 = uidropdown(app.UIFigure);
            app.OptDD1.ValueChangedFcn = createCallbackFcn(app, @OptDD1ValueChanged, true);
            app.OptDD1.Enable = 'off';
            app.ScheduleListBoxLabel = uilabel(app.UIFigure);
            app.ScheduleListBoxLabel.Text = 'Schedule';
            app.ScheduleListBox = uilistbox(app.UIFigure);
            app.ScheduleListBox.Items = {};
            app.ScheduleListBox.ValueChangedFcn = createCallbackFcn(app, @ScheduleListBoxValueChanged, true);
            app.ScheduleListBox.Value = {};
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.RemoveButton = uibutton(app.UIFigure, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.UpButton = uibutton(app.UIFigure, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = '↑';
            app.DownButton = uibutton(app.UIFigure, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = '↓';
            app.OptDD2Label = uilabel(app.UIFigure);
            app.OptDD2Label.Text = 'Options 2';
            app.OptDD2 = uidropdown(app.UIFigure);
            app.OptDD2.ValueChangedFcn = createCallbackFcn(app, @OptDD2ValueChanged, true);
            app.OptDD2.Enable = 'off';
            app.LoadMaskButton = uibutton(app.UIFigure, 'push');
            app.LoadMaskButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMaskButtonPushed, true);
            app.LoadMaskButton.Enable = 'off';
            app.LoadMaskButton.Text = 'Load Mask';
            app.SelImDDLabel = uilabel(app.UIFigure);
            app.SelImDDLabel.Text = 'Select Image';
            app.SelImDD = uidropdown(app.UIFigure);
            app.SelImDD.ValueChangedFcn = createCallbackFcn(app, @SelImDDValueChanged, true);
            app.SelImDD.Enable = 'off';
            app.OperationTree = uitree(app.UIFigure);
            app.OperationTree.SelectionChangedFcn = createCallbackFcn(app, @OperationTreeSelectionChanged, true);
            app.BasicOperationsNode = uitreenode(app.OperationTree);
            app.BasicOperationsNode.Text = 'Basic Operations';
            app.contrastNode = uitreenode(app.BasicOperationsNode);
            app.contrastNode.Text = 'contrast';
            app.denoiseNode = uitreenode(app.BasicOperationsNode);
            app.denoiseNode.Text = 'denoise';
            app.flatfieldNode = uitreenode(app.BasicOperationsNode);
            app.flatfieldNode.Text = 'flatfield';
            app.thresholdNode = uitreenode(app.BasicOperationsNode);
            app.thresholdNode.Text = 'threshold';
            app.invertNode = uitreenode(app.BasicOperationsNode);
            app.invertNode.Text = 'invert';
            app.ConversionNode = uitreenode(app.OperationTree);
            app.ConversionNode.Text = 'Conversion';
            app.channelNode_2 = uitreenode(app.ConversionNode);
            app.channelNode_2.Text = 'channel';
            app.binarizeNode_2 = uitreenode(app.ConversionNode);
            app.binarizeNode_2.Text = 'binarize';
            app.isolatechannNode = uitreenode(app.ConversionNode);
            app.isolatechannNode.Text = 'isolatechann';
            app.MorphologicalOperationsNode = uitreenode(app.OperationTree);
            app.MorphologicalOperationsNode.Text = 'Morphological Operations';
            app.erodeNode = uitreenode(app.MorphologicalOperationsNode);
            app.erodeNode.Text = 'erode';
            app.dilateNode = uitreenode(app.MorphologicalOperationsNode);
            app.dilateNode.Text = 'dilate';
            app.closeNode = uitreenode(app.MorphologicalOperationsNode);
            app.closeNode.Text = 'close';
            app.openNode = uitreenode(app.MorphologicalOperationsNode);
            app.openNode.Text = 'open';
            app.clearborderNode = uitreenode(app.MorphologicalOperationsNode);
            app.clearborderNode.Text = 'clearborder';
            app.watershedNode = uitreenode(app.MorphologicalOperationsNode);
            app.watershedNode.Text = 'watershed';
            app.removeNode = uitreenode(app.MorphologicalOperationsNode);
            app.removeNode.Text = 'outline';
            app.skelNode = uitreenode(app.MorphologicalOperationsNode);
            app.skelNode.Text = 'skel';
            app.bothatNode = uitreenode(app.MorphologicalOperationsNode);
            app.bothatNode.Text = 'bothat';
            app.tophatNode = uitreenode(app.MorphologicalOperationsNode);
            app.tophatNode.Text = 'tophat';
            app.bridgeNode = uitreenode(app.MorphologicalOperationsNode);
            app.bridgeNode.Text = 'bridge';
            app.cleanNode = uitreenode(app.MorphologicalOperationsNode);
            app.cleanNode.Text = 'clean';
            app.thickenNode = uitreenode(app.MorphologicalOperationsNode);
            app.thickenNode.Text = 'thicken';
            app.thinNode = uitreenode(app.MorphologicalOperationsNode);
            app.thinNode.Text = 'thin';
            app.ellipsifyNode = uitreenode(app.MorphologicalOperationsNode);
            app.ellipsifyNode.Text = 'ellipsify';
            app.circularizeNode = uitreenode(app.MorphologicalOperationsNode);
            app.circularizeNode.Text = 'circularize';
            app.ComponentPropertyFiltersNode = uitreenode(app.OperationTree);
            app.ComponentPropertyFiltersNode.Text = 'Component Property Filters';
            app.areaNode = uitreenode(app.ComponentPropertyFiltersNode);
            app.areaNode.Text = 'area';
            app.convexareaNode = uitreenode(app.ComponentPropertyFiltersNode);
            app.convexareaNode.Text = 'convexarea';
            app.eccentricityNode = uitreenode(app.ComponentPropertyFiltersNode);
            app.eccentricityNode.Text = 'eccentricity';
            app.equivdiameterNode = uitreenode(app.ComponentPropertyFiltersNode);
            app.equivdiameterNode.Text = 'equivdiameter';
            app.eulernumberNode = uitreenode(app.ComponentPropertyFiltersNode);
            app.eulernumberNode.Text = 'eulernumber';
            app.extentNode = uitreenode(app.ComponentPropertyFiltersNode);
            app.extentNode.Text = 'extent';
            app.filledareaNode = uitreenode(app.ComponentPropertyFiltersNode);
            app.filledareaNode.Text = 'filledarea';
            app.majoraxislengthNode = uitreenode(app.ComponentPropertyFiltersNode);
            app.majoraxislengthNode.Text = 'majoraxislength';
            app.minoraxislengthNode = uitreenode(app.ComponentPropertyFiltersNode);
            app.minoraxislengthNode.Text = 'minoraxislength';
            app.orientationNode = uitreenode(app.ComponentPropertyFiltersNode);
            app.orientationNode.Text = 'orientation';
            app.perimeterNode = uitreenode(app.ComponentPropertyFiltersNode);
            app.perimeterNode.Text = 'perimeter';
            app.circularityNode = uitreenode(app.ComponentPropertyFiltersNode);
            app.circularityNode.Text = 'circularity';
            app.numbwfiltNode = uitreenode(app.ComponentPropertyFiltersNode);
            app.numbwfiltNode.Text = 'numbwfilt';
            app.solidityNode = uitreenode(app.ComponentPropertyFiltersNode);
            app.solidityNode.Text = 'solidity';
            app.IntensityPropertyFiltersNode = uitreenode(app.OperationTree);
            app.IntensityPropertyFiltersNode.Text = 'Intensity Property Filters';
            app.maxintensityNode = uitreenode(app.IntensityPropertyFiltersNode);
            app.maxintensityNode.Text = 'maxintensity';
            app.minintensityNode = uitreenode(app.IntensityPropertyFiltersNode);
            app.minintensityNode.Text = 'minintensity';
            app.meanintensityNode = uitreenode(app.IntensityPropertyFiltersNode);
            app.meanintensityNode.Text = 'meanintensity';
            app.MaskOperationsNode = uitreenode(app.OperationTree);
            app.MaskOperationsNode.Text = 'Mask Operations';
            app.maskNode = uitreenode(app.MaskOperationsNode);
            app.maskNode.Text = 'mask';
            app.asmaskNode = uitreenode(app.MaskOperationsNode);
            app.asmaskNode.Text = 'asmask';
            app.cropbymaskNode = uitreenode(app.MaskOperationsNode);
            app.cropbymaskNode.Text = 'cropbymask';
            app.neighborthreshNode = uitreenode(app.MaskOperationsNode);
            app.neighborthreshNode.Text = 'neighborthresh';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Oper'; 'Param'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.ParmBox = uilistbox(app.UIFigure);
            app.ParmBox.Items = {};
            app.ParmBox.Value = {};
            app.pauseButton = uibutton(app.UIFigure, 'push');
            app.pauseButton.ButtonPushedFcn = createCallbackFcn(app, @pauseButtonPushed, true);
            app.pauseButton.Text = 'pause';
            app.CurIDEditFieldLabel = uilabel(app.UIFigure);
            app.CurIDEditFieldLabel.Text = 'CurID';
            app.CurIDEditField = uieditfield(app.UIFigure, 'numeric');
            app.HoldEndCheckBox = uicheckbox(app.UIFigure);
            app.HoldEndCheckBox.ValueChangedFcn = createCallbackFcn(app, @HoldEndCheckBoxValueChanged, true);
            app.HoldEndCheckBox.Text = 'Hold End';
            app.HoldEndCheckBox.Value = true;
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.OtherOperationsPanel = uipanel(app.UIFigure);
            app.OtherOperationsPanel.Title = 'Other Operations';
            app.PolarityAndOrientationAnalysisButton = uibutton(app.OtherOperationsPanel, 'push');
            app.PolarityAndOrientationAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @PolarityAndOrientationAnalysisButtonPushed, true);
            app.PolarityAndOrientationAnalysisButton.Text = {'Polarity And Orientation Analysis'; ''};
            app.MaskToComponentImagesButton = uibutton(app.OtherOperationsPanel, 'push');
            app.MaskToComponentImagesButton.ButtonPushedFcn = createCallbackFcn(app, @MaskToComponentImagesButtonPushed, true);
            app.MaskToComponentImagesButton.Text = {'Mask To Component Images'; ''};
            app.ComponentImagesToMaskButton = uibutton(app.OtherOperationsPanel, 'push');
            app.ComponentImagesToMaskButton.ButtonPushedFcn = createCallbackFcn(app, @ComponentImagesToMaskButtonPushed, true);
            app.ComponentImagesToMaskButton.Text = {'Component Images To Mask'; ''};
            app.TrainNetworkButton = uibutton(app.OtherOperationsPanel, 'push');
            app.TrainNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @TrainNetworkButtonPushed, true);
            app.TrainNetworkButton.Text = 'Train Network';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SegmentImageClean

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end